classdef Heart < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure               matlab.ui.Figure
        YourBPMEditField       matlab.ui.control.NumericEditField
        YourBPMEditFieldLabel  matlab.ui.control.Label
        Image_2                matlab.ui.control.Image
        Image2                 matlab.ui.control.Image
        Image                  matlab.ui.control.Image
        Button                 matlab.ui.control.Button
        UIAxes2                matlab.ui.control.UIAxes
        UIAxes                 matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: Button
        function ButtonPushed(app, event)
      
            [fileName, pathName] = uigetfile({'*.mp4;*.mp3;*.wav;'},'Select a video');
            if ~isequal(fileName,0)
                originalvid =  VideoReader(fullfile(pathName,fileName)); %imread('C:\image.jpg');
                vidHeight= originalvid.Height;
                vidWidth = originalvid.Width;
                nChannels = 3;
                fr = originalvid.FrameRate;
                len = originalvid.NumFrames;
                Heart_data_type= struct('cdata', zeros(vidHeight, ...
                    vidWidth, nChannels, 'uint8'), 'colormap', []);
                T = 1/fr;
                tlen = len/fr;
                t1 = linspace(0, tlen, len);
                f = -fr/2:fr/len:fr/2-fr/len;
                mov(1:len) = struct('cdata', zeros(vidHeight, vidWidth, ...
                    nChannels, 'uint8'), 'colormap', []);

                app.Button.Text = 'Processing...';
                % Put text on top of icon
                app.Button.IconAlignment = 'bottom';
                % Create waitbar with same color as button
                wbar = permute(repmat(app.Button.BackgroundColor,15,1,200),[1,3,2]);
                % Black frame around waitbar
                wbar([1,end],:,:) = 0;
                wbar(:,[1,end],:) = 0;
                % Load the empty waitbar to the button
                app.Button.Icon = wbar;
                % Loop through something and update waitbar
%                 n = 10;
%                 for i = 1:n
%                     % Update image data (royalblue)
%                     % if mod(i,10)==0 % update every 10 cycles; improves efficiency
%                     
%                     % end
%                 end
               
                % Grab all the frames and put into our struct
                for k = 1 : len
                    mov(k).cdata = read(originalvid, k);
                end

                % Find the center 5th to average
                % make a function to do this


                centerVerMin = ceil((vidHeight/5) ...
                    + (vidHeight/5));
                centerVerMax = ceil((vidHeight/5) ...
                    + (vidHeight/5) + (vidHeight/5));

                centerHorMin = ceil((vidWidth/5) ...
                    + (vidWidth/5));
                centerHorMax = ceil((vidWidth/5)  ...
                    + (vidWidth/5) +  + (vidWidth/5));

                sampleHor = centerHorMax - centerHorMin;
                sampleVer = centerVerMax - centerVerMin;

                sample = zeros(sampleVer, sampleHor, len);


                t = zeros(len);
                avg = zeros(len);

                for k = 1 : len

                   currentProg = min(round((size(wbar,2)-2)*(k/len)), ...
                       size(wbar,2)-2);
                    RGB = app.Button.Icon;
                    RGB(2:end-1, 2:currentProg+1, 1) = 0.6350  ; % (royalblue)
                    RGB(2:end-1, 2:currentProg+1, 2) = 0.0780 ;
                    RGB(2:end-1, 2:currentProg+1, 3) = 0.1840;
                    app.Button.Icon = RGB;
                    % Pause to slow down animation
                    pause(.3)
                    for j = centerHorMin : centerHorMax
                        for i = centerVerMin : centerVerMax
                            sampleHorIndex = j - centerHorMin + 1;
                            sampleVerIndex = i - centerVerMin + 1;
                            sample(sampleVerIndex, ...
                                sampleHorIndex, k) = ...
                                mov(k).cdata(i, j, 1);
                        end
                    end
                    t(k) = k;   % value of k (frames) for graphing
                    avg(k) = mean(mean(sample(:,:,k)));
                

                end

                 % remove waitbar
                app.Button.Icon = '';
                % Change button name
                app.Button.Text = 'Browse';

                Y = fftshift(fft(avg));
                plot(app.UIAxes,t,avg ,'LineWidth',2 ,'Color', '#eb4939');
                

                title(app.UIAxes,['{\bf Mangitude of Frequency' ...
                    ' to find Heart Rate }'], 'color', ...
                    '#92b9e4','FontSize', ...
                    14,'FontName' ...
                    ,'TimeNewRoman'); grid on
                xlabel(app.UIAxes,'Frame', 'color', '#ff5d8e' ...
                    ,'FontSize', ...
                    14,'FontName' ...
                    ,'TimeNewRoman');
                ylabel(app.UIAxes,'Pixel intensity', ...
                    'color', '#ff5d8e','FontSize', ...
                    14,'FontName' ...
                    ,'TimeNewRoman');
                axis(app.UIAxes, 'auto')
                  yo=1/len*abs(Y) ;
                 
                stem(app.UIAxes2,f,yo,'filled', ...
                    'LineWidth',2 ,'Color', '#eb4939');
                title(app.UIAxes2,['{\bf Mangitude of ' ...
                    'Frequency to find Heart Rate }'], 'color', ...
                    '#92b9e4','FontSize', ...
                    14,'FontName' ...
                    ,'TimeNewRoman'); grid on
                xlabel(app.UIAxes2,'Frequency (Hz)', ...
                    'color', '#ff5d8e','FontSize', ...
                    14,'FontName','TimeNewRoman');
                ylabel(app.UIAxes2,'Magnitude', 'color', ...
                    '#ff5d8e','FontSize', ...
                    14,'FontName' ...
                    ,'TimeNewRoman');
                axis(app.UIAxes2, 'tight')
                
                
                r1=max(real(yo), [], 'all');
                app.YourBPMEditField.Value=r1;


                if (r1>100)
%                 app.YourBPMEditFieldLabel_2.Text='ice';
           
                end 
            end
        end

        % Callback function
        function TextAreaValueChanged(app, event)
%             value = app.TextArea.Value;
                  
       your_number=10  ;
       set(handles.TextAreaValue,'string',num2str(your_number))

            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            colormap(app.UIFigure, 'cool');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            xlabel(app.UIAxes, 'Frame')
            ylabel(app.UIAxes, 'Pixel insitisy ')
            zlabel(app.UIAxes, 'Z')
            colormap(app.UIAxes, 'cool')
            app.UIAxes2 = uiaxes(app.UIFigure);
            xlabel(app.UIAxes2, 'Frequency(Hz)')
            ylabel(app.UIAxes2, 'Magnitude')
            zlabel(app.UIAxes2, 'Z')
            colormap(app.UIAxes2, 'cool')
            app.Button = uibutton(app.UIFigure, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.Button.Icon = fullfile(pathToMLAPP, 'Heart2.png');
            app.Button.Text = '';
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageSource = fullfile(pathToMLAPP, 'heartbeat_1_Regular.gif');
            app.Image2 = uiimage(app.UIFigure);
            app.Image2.ImageSource = fullfile(pathToMLAPP, 'ezgif.com-gif-maker.gif');
            app.Image_2 = uiimage(app.UIFigure);
            app.Image_2.ImageSource = fullfile(pathToMLAPP, '62f7f4c8d9075178342394.gif');
            app.YourBPMEditFieldLabel = uilabel(app.UIFigure);
            app.YourBPMEditFieldLabel.Text = 'Your BPM';
            app.YourBPMEditField = uieditfield(app.UIFigure, 'numeric');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Heart

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end